classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterApp_lqy                   matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        FIRFilterWindowTypeButtonGroup  matlab.ui.container.ButtonGroup
        HammingButton                   matlab.ui.control.RadioButton
        BlackmanButton                  matlab.ui.control.RadioButton
        HannButton                      matlab.ui.control.RadioButton
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        LowpassButton                   matlab.ui.control.RadioButton
        HighPassButton                  matlab.ui.control.RadioButton
        BandpassButton                  matlab.ui.control.RadioButton
        BandStopButton                  matlab.ui.control.RadioButton
        LowFrequencyEditFieldLabel      matlab.ui.control.Label
        LowFrequencyEditField           matlab.ui.control.NumericEditField
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        DigitalfilteringsystemLabel     matlab.ui.control.Label
        HighFrequencyLabel              matlab.ui.control.Label
        HighFrequencyEditField          matlab.ui.control.NumericEditField
        OKButton                        matlab.ui.control.Button
        SinSignalButton                 matlab.ui.control.Button
        AddNoiseButton                  matlab.ui.control.Button
        FromFileButton                  matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        PlayButton_original             matlab.ui.control.Button
        PlayButton_with_noise           matlab.ui.control.Button
        PlayButton_filtered             matlab.ui.control.Button
        IIRFilterTypeButtonGroup        matlab.ui.container.ButtonGroup
        ButterworthButton               matlab.ui.control.RadioButton
        Chebyshev1Button                matlab.ui.control.RadioButton
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        IIRButton                       matlab.ui.control.RadioButton
        FIRButton                       matlab.ui.control.RadioButton
        BandPanel                       matlab.ui.container.Panel
        LowFreq_2EditFieldLabel         matlab.ui.control.Label
        LowFreq_2EditField              matlab.ui.control.NumericEditField
        HighFreq_2EditFieldLabel        matlab.ui.control.Label
        HighFreq_2EditField             matlab.ui.control.NumericEditField
        AmptitudePanel                  matlab.ui.container.Panel
        A_pEditFieldLabel               matlab.ui.control.Label
        A_pEditField                    matlab.ui.control.NumericEditField
        A_sEditFieldLabel               matlab.ui.control.Label
        A_sEditField                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        signal;
        path;
        filtered_signal;
        flag_window;
        flag_iir_type;
        flag1;
        sampling_rate;
        wav_path;
        signal_with_noise;
        
        % 以下私有属性用于对话窗口
        DialogApp_sin_signal
        Main_received_sampling_rate;
        
        DialogApp_add_noise
        Main_received_noise;
        
    end
    
    
    methods (Access = private)
        
        function results = fft_function(app,para_signal,uiaxesflag)
            
            length_signal=length(para_signal);
            x=(0:1:length_signal-1)/length_signal*2;
            fft_signal=abs(fft(para_signal));
            
            if uiaxesflag==1
                plot(app.UIAxes_2,x,fft_signal);
            elseif  uiaxesflag==2
                plot(app.UIAxes2_2,x,fft_signal);
            elseif  uiaxesflag==3
                plot(app.UIAxes3_2,x,fft_signal);
            end
            
        end
    end
    
    
    methods (Access = private)
        
        function results = filterplot_filtersignal_fir(app,lowfre,highfre)
            
            order = ceil(6.2/(highfre - lowfre));
            order = mod(order+1,2)+order;
            order = order-1;

            wp0 = (lowfre + highfre)/2;            
            
            if app.flag_window==0
                if app.flag1==0
                    filterdata=fir1(order,wp0,hamming(order+1));
                elseif app.flag1==1
                    filterdata=fir1(order,wp0,'high',hamming(order+1));
                elseif app.flag1==2
                    filterdata=fir1(order,[lowfre,highfre],hamming(order+1));
                elseif app.flag1==3
                    filterdata=fir1(order,[lowfre,highfre],'stop',hamming(order+1));
                end
                
            elseif app.flag_window==1
                if app.flag1==0
                    filterdata=fir1(order,wp0,blackman(order+1));
                elseif app.flag1==1
                    filterdata=fir1(order,wp0,'high',blackman(order+1));
                elseif app.flag1==2
                    filterdata=fir1(order,[lowfre,highfre],blackman(order+1));
                elseif app.flag1==3
                    filterdata=fir1(order,[lowfre,highfre],'stop',blackman(order+1));
                end
                
            elseif app.flag_window==2
                if app.flag1==0
                    filterdata=fir1(order,wp0,hann(order+1));
                elseif app.flag1==1
                    filterdata=fir1(order,wp0,'high',hann(order+1));
                elseif app.flag1==2
                    filterdata=fir1(order,[lowfre,highfre],hann(order+1));
                elseif app.flag1==3
                    filterdata=fir1(order,[lowfre,highfre],'stop',hann(order+1));
                end
            end
            
            [h,n]=freqz(filterdata,1,512);
            plot(app.UIAxes4, n/(pi), db(abs(h)));
            plot(app.UIAxes5, n/(pi), 180/(pi)*unwrap(angle(h)));
            
            app.filtered_signal=conv(filterdata,app.signal_with_noise);
            
            plot(app.UIAxes2, app.filtered_signal);
            fft_function(app,app.filtered_signal,2);
            
        end
    end
    
    methods (Access = public)
        
        function results = Receive_DataFrom_SinSignalGenerate(app, calc_signal, calc_sampling_rate)
            % 公共函数名为Receive_DataFrom_SinSignalGenerate，该函数将在SinSignalGenerate对话窗内调用，
            % 输入参数calc_signal由对话窗给出
            
            app.signal = calc_signal;
            % 对话窗返回的正弦信号存储在signal中
            
            app.signal_with_noise = app.signal;
            
            app.Main_received_sampling_rate = calc_sampling_rate;
            % 对话窗返回的采样频率存储在Main_received_sampling_rate中
            app.sampling_rate = app.Main_received_sampling_rate;
            
            % 根据刚得到的正弦信号作时域和频域的图
            plot(app.UIAxes, app.signal);
            fft_function(app, app.signal, 1);
        end
        
        function results = Receive_DataFrom_NoiseGenerate(app, calc_noise)
            % 公共函数名为Receive_DataFrom_NoiseGenerate，该函数将在SinSignalGenerate对话窗内调用，
            % 输入参数calc_noise由对话窗给出
            app.Main_received_noise = calc_noise;
            
            app.signal_with_noise = app.Main_received_noise + app.signal;
            
            plot(app.UIAxes3, app.signal_with_noise);
            fft_function(app, app.signal_with_noise, 3);
        end
        
        function results = filterplot_filtersignal_iir_no_band(app, lowfre, highfre, A_p, A_s)
            
            if app.flag_iir_type == 0
                if app.flag1 == 0
                    [N, wc] = buttord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = butter(N,wc,'s');
                    [numd, dend] = bilinear(numa,dena,1);
                elseif app.flag1 == 1
                    [N, wc] = buttord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = butter(N,wc,'s');
                    [numt, dent] = lp2hp(numa, dena, highfre);
                    [numd, dend] = bilinear(numt, dent, 1);
                end
            elseif app.flag_iir_type == 1
                if app.flag1 == 0
                    [N, wc] = cheb1ord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = cheby1(N,A_p,wc,'s');
                    [numd, dend] = bilinear(numa,dena,1);
                elseif app.flag1 == 1
                    [N, wc] = cheb1ord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = cheby1(N,A_p,wc,'s');
                    [numt, dent] = lp2hp(numa, dena, highfre);
                    [numd, dend] = bilinear(numt, dent, 1);
                end
            end
            
            [h, n] = freqz(numd, dend, 512);

            plot(app.UIAxes4, n/(pi), db(abs(h)));
            plot(app.UIAxes5, n/(pi), 180/(pi)*unwrap(angle(h)));

            app.filtered_signal = filter(numd, dend, app.signal_with_noise);
            
            plot(app.UIAxes2, app.filtered_signal);
            fft_function(app,app.filtered_signal,2);
        end
        
        function results = filterplot_filtersignal_iir_band(app, wp1, wp2, ws1, ws2, A_p, A_s)
            
            if app.flag_iir_type == 0
                if app.flag1 == 2
                    B = wp2 - wp1;
                    w0 = sqrt(wp1*wp2);
                    wLs1 = (ws1*ws1 - w0*w0)/B*ws1;
                    wLs2 = (ws2*ws2 - w0*w0)/B*ws2;
                    
                    highfre = min(abs(wLs1),abs(wLs2));
                    lowfre = 1;
                    
                    [N, wc] = buttord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = butter(N,wc,'s');
                    [numt, dent] = lp2bp(numa, dena, w0*pi, B*pi);
                    [numd, dend] = bilinear(numt,dent,1);
                elseif app.flag1 == 3

                    B = ws2 - ws1;
                    w0 = sqrt(ws1*ws2);
                    wLp1 = B*wp1/(wp1*wp1 - w0*w0);
                    wLp2 = B*wp2/(wp2*wp2 - w0*w0);
                    
                    lowfre = max(abs(wLp1), abs(wLp2));
                    highfre = 1;
                    
                    [N, wc] = buttord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = butter(N,wc,'s');
                    [numt, dent] = lp2bs(numa, dena, w0*pi, B*pi); 
                    [numd, dend] = bilinear(numt, dent, 1);
                end
            elseif app.flag_iir_type == 1
                if app.flag1 == 2
                    B = wp2 - wp1;
                    w0 = sqrt(wp1*wp2);
                    wLs1 = (ws1*ws1 - w0*w0)/B*ws1;
                    wLs2 = (ws2*ws2 - w0*w0)/B*ws2;
                    
                    highfre = min(abs(wLs1),abs(wLs2));
                    lowfre = 1;
                    
                    [N, wc] = cheb1ord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = cheby1(N,A_p,wc,'s');
                    [numt, dent] = lp2bp(numa, dena, w0*pi, B*pi);
                    [numd, dend] = bilinear(numt,dent,1);
                elseif app.flag1 == 3

                    B = ws2 - ws1;
                    w0 = sqrt(ws1*ws2);
                    wLp1 = B*wp1/(wp1*wp1 - w0*w0);
                    wLp2 = B*wp2/(wp2*wp2 - w0*w0);
                    
                    lowfre = max(abs(wLp1), abs(wLp2));
                    highfre = 1;
                    
                    [N, wc] = cheb1ord(lowfre, highfre, A_p, A_s,'s');
                    [numa, dena] = cheby1(N,A_p, wc,'s');
                    [numt, dent] = lp2bs(numa, dena, w0*pi, B*pi); 
                    [numd, dend] = bilinear(numt, dent, 1);
                end
            end
            
            [h, n] = freqz(numd, dend, 512);

            plot(app.UIAxes4, n/(pi), db(abs(h)));
            plot(app.UIAxes5, n/(pi), 180/(pi)*unwrap(angle(h)));

            app.filtered_signal = filter(numd, dend, app.signal_with_noise);
            
            plot(app.UIAxes2, app.filtered_signal);
            fft_function(app,app.filtered_signal,2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.FIRFilterWindowTypeButtonGroup.Visible = 'off';
            app.BandPanel.Visible = 'off';
        end

        % Callback function
        function openfile(app, event)
            
        end

        % Button pushed function: OKButton
        function OK(app, event)
            
            if app.LowpassButton.Value
                app.flag1=0;
            elseif app.HighPassButton.Value
                app.flag1=1;
            elseif app.BandpassButton.Value
                app.flag1=2;
            elseif app.BandStopButton.Value
                app.flag1=3;
            end

            if app.FIRButton.Value
                if app.HammingButton.Value
                    app.flag_window=0;
                elseif app.BlackmanButton.Value
                    app.flag_window=1;
                else
                    app.flag_window=2;
                end
                
                filterplot_filtersignal_fir(app,app.LowFrequencyEditField.Value,app.HighFrequencyEditField.Value);
            
            elseif app.IIRButton.Value
                if app.ButterworthButton.Value
                    app.flag_iir_type=0;
                elseif app.Chebyshev1Button.Value
                    app.flag_iir_type=1;
                end
                
                if app.flag1 == 0 || app.flag1 == 1
                    filterplot_filtersignal_iir_no_band(app,app.LowFrequencyEditField.Value,app.HighFrequencyEditField.Value,app.A_pEditField.Value,app.A_sEditField.Value);
                elseif app.flag1 == 2 || app.flag1 == 3
                    filterplot_filtersignal_iir_band(app,app.LowFrequencyEditField.Value,app.HighFrequencyEditField.Value,app.LowFreq_2EditField.Value,app.HighFreq_2EditField.Value,app.A_pEditField.Value,app.A_sEditField.Value);                    
                end
            end        
        end

        % Callback function
        function save(app, event)
            file1=app.FileNameEditField.Value;
            filepath=fullfile(app.path,file1);
            dlmwrite(filepath,app.filtered_signal,'delimiter',' ');
        end

        % Button pushed function: SinSignalButton
        function SinSignalButtonPushed(app, event)
            app.SinSignalButton.Enable = 'off';
            % 对话窗存在时，禁用主窗口的正弦信号加载按钮，避免打开多个对话窗
            
            app.DialogApp_sin_signal = sin_signal_generate(app);
        end

        % Close request function: FilterApp_lqy
        function FilterApp_lqyCloseRequest(app, event)
            delete(app.DialogApp_add_noise)
            delete(app.DialogApp_sin_signal)
            delete(app)
            
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            app.AddNoiseButton.Enable = 'off';
            % 对话窗存在时，需要禁止主窗口的加噪声功能，避免打开多个对话窗
            
            app.DialogApp_add_noise = noise_generate(app, app.signal, app.sampling_rate);
            % 调用对话窗，输入参数分别为app、app.signal、app.sampling_rate
        end

        % Button pushed function: FromFileButton
        function FromFileButtonPushed(app, event)
            [file,app.wav_path] = uigetfile('*.wav');
            filepath=fullfile(app.wav_path,file);
            
            [app.signal, app.sampling_rate] = audioread(filepath,[1,100000]);
            signal_size = size(app.signal);
            if signal_size(2) == 1
                app.signal = app.signal';
            end
            
            app.signal_with_noise = app.signal;
            
            plot(app.UIAxes,app.signal);
            fft_function(app,app.signal,1);
        end

        % Button pushed function: PlayButton_original
        function PlayButton_originalPushed(app, event)
            sound(app.signal, app.sampling_rate);
        end

        % Button pushed function: PlayButton_with_noise
        function PlayButton_with_noisePushed(app, event)
            sound(app.signal_with_noise, app.sampling_rate);
        end

        % Button pushed function: PlayButton_filtered
        function PlayButton_filteredPushed(app, event)
            sound(app.filtered_signal, app.sampling_rate);
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup.SelectedObject;
            if app.LowpassButton.Value
                app.flag1=0;
            elseif app.HighPassButton.Value
                app.flag1=1;
            elseif app.BandpassButton.Value
                app.flag1=2;
            elseif app.BandStopButton.Value
                app.flag1=3;
            end
            
            if selectedButton == app.FIRButton
                app.FIRFilterWindowTypeButtonGroup.Visible = 'on';
                app.IIRFilterTypeButtonGroup.Visible = 'off';
                app.BandPanel.Visible = 'off';
                app.AmptitudePanel.Visible = 'off';
            else
                app.FIRFilterWindowTypeButtonGroup.Visible = 'off';
                app.IIRFilterTypeButtonGroup.Visible = 'on';

                app.AmptitudePanel.Visible = 'on';                
                if app.flag1 == 0 || app.flag1 == 1
                    app.BandPanel.Visible = 'off';
                elseif app.flag1 == 2 || app.flag1 == 3
                    app.BandPanel.Visible = 'on';
                end
            end
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            if app.FilterTypeButtonGroup.SelectedObject == app.IIRButton
                if selectedButton == app.BandpassButton || selectedButton == app.BandStopButton
                    app.BandPanel.Visible = 'on';
                elseif selectedButton == app.LowpassButton || selectedButton == app.HighPassButton
                    app.BandPanel.Visible = 'off';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterApp_lqy = uifigure('Visible', 'off');
            app.FilterApp_lqy.Name = 'FilterApp';
            app.FilterApp_lqy.CloseRequestFcn = createCallbackFcn(app, @FilterApp_lqyCloseRequest, true);
            app.UIAxes = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes_2, 'FFT Spectrum')
            xlabel(app.UIAxes_2, 'Digtal frequency')
            ylabel(app.UIAxes_2, 'Magnitude')
            app.UIAxes_2.XLim = [0 1];
            app.FIRFilterWindowTypeButtonGroup = uibuttongroup(app.FilterApp_lqy);
            app.FIRFilterWindowTypeButtonGroup.Title = 'FIR Filter- Window Type ';
            app.HammingButton = uiradiobutton(app.FIRFilterWindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.BlackmanButton = uiradiobutton(app.FIRFilterWindowTypeButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            app.HannButton = uiradiobutton(app.FIRFilterWindowTypeButtonGroup);
            app.HannButton.Text = 'Hann';
            app.FilterButtonGroup = uibuttongroup(app.FilterApp_lqy);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'FIR Filter';
            app.LowpassButton = uiradiobutton(app.FilterButtonGroup);
            app.LowpassButton.Text = 'Low-pass';
            app.LowpassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.FilterButtonGroup);
            app.HighPassButton.Text = 'High-Pass';
            app.BandpassButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassButton.Text = 'Band-pass';
            app.BandStopButton = uiradiobutton(app.FilterButtonGroup);
            app.BandStopButton.Text = 'Band-Stop';
            app.LowFrequencyEditFieldLabel = uilabel(app.FilterApp_lqy);
            app.LowFrequencyEditFieldLabel.Text = 'Low Frequency';
            app.LowFrequencyEditField = uieditfield(app.FilterApp_lqy, 'numeric');
            app.LowFrequencyEditField.Value = 0.3;
            app.UIAxes4 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes4, 'Filter')
            xlabel(app.UIAxes4, 'Digtal frequency')
            ylabel(app.UIAxes4, 'Magnitude(dB)')
            app.UIAxes2_2 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes2_2, 'FFT of Filtered Signal')
            xlabel(app.UIAxes2_2, 'Digtal frequency')
            ylabel(app.UIAxes2_2, 'Magnitude')
            app.UIAxes2_2.XLim = [0 1];
            app.DigitalfilteringsystemLabel = uilabel(app.FilterApp_lqy);
            app.DigitalfilteringsystemLabel.Text = 'Digital filtering system';
            app.HighFrequencyLabel = uilabel(app.FilterApp_lqy);
            app.HighFrequencyLabel.Text = 'High Frequency';
            app.HighFrequencyEditField = uieditfield(app.FilterApp_lqy, 'numeric');
            app.HighFrequencyEditField.Value = 0.5;
            app.OKButton = uibutton(app.FilterApp_lqy, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OK, true);
            app.OKButton.Text = 'OK';
            app.SinSignalButton = uibutton(app.FilterApp_lqy, 'push');
            app.SinSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SinSignalButtonPushed, true);
            app.SinSignalButton.Text = 'Multi Sin';
            app.AddNoiseButton = uibutton(app.FilterApp_lqy, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.FromFileButton = uibutton(app.FilterApp_lqy, 'push');
            app.FromFileButton.ButtonPushedFcn = createCallbackFcn(app, @FromFileButtonPushed, true);
            app.FromFileButton.Text = 'From File';
            app.UIAxes3 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes3, 'Signal with Noise')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes3_2, 'FFT Spectrum of Signal with Noise')
            xlabel(app.UIAxes3_2, 'Digtal frequency')
            ylabel(app.UIAxes3_2, 'Magnitude')
            app.UIAxes3_2.XLim = [0 1];
            app.UIAxes2 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes2, 'Filtered Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes5 = uiaxes(app.FilterApp_lqy);
            title(app.UIAxes5, 'Filter Phase')
            xlabel(app.UIAxes5, 'Digtal frequency')
            ylabel(app.UIAxes5, 'Phase')
            app.UIAxes5.XLim = [0 1];
            app.PlayButton_original = uibutton(app.FilterApp_lqy, 'push');
            app.PlayButton_original.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_originalPushed, true);
            app.PlayButton_original.Text = 'Play';
            app.PlayButton_with_noise = uibutton(app.FilterApp_lqy, 'push');
            app.PlayButton_with_noise.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_with_noisePushed, true);
            app.PlayButton_with_noise.Text = 'Play';
            app.PlayButton_filtered = uibutton(app.FilterApp_lqy, 'push');
            app.PlayButton_filtered.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_filteredPushed, true);
            app.PlayButton_filtered.Text = 'Play';
            app.IIRFilterTypeButtonGroup = uibuttongroup(app.FilterApp_lqy);
            app.IIRFilterTypeButtonGroup.Title = 'IIR Filter Type';
            app.ButterworthButton = uiradiobutton(app.IIRFilterTypeButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.ButterworthButton.Value = true;
            app.Chebyshev1Button = uiradiobutton(app.IIRFilterTypeButtonGroup);
            app.Chebyshev1Button.Text = 'Chebyshev-1';
            app.FilterTypeButtonGroup = uibuttongroup(app.FilterApp_lqy);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.IIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.BandPanel = uipanel(app.FilterApp_lqy);
            app.BandPanel.Title = 'Band';
            app.LowFreq_2EditFieldLabel = uilabel(app.BandPanel);
            app.LowFreq_2EditFieldLabel.Text = 'Low Freq_2';
            app.LowFreq_2EditField = uieditfield(app.BandPanel, 'numeric');
            app.LowFreq_2EditField.Value = 0.35;
            app.HighFreq_2EditFieldLabel = uilabel(app.BandPanel);
            app.HighFreq_2EditFieldLabel.Text = 'High Freq_2';
            app.HighFreq_2EditField = uieditfield(app.BandPanel, 'numeric');
            app.HighFreq_2EditField.Value = 0.45;
            app.AmptitudePanel = uipanel(app.FilterApp_lqy);
            app.AmptitudePanel.Title = 'Amptitude';
            app.A_pEditFieldLabel = uilabel(app.AmptitudePanel);
            app.A_pEditFieldLabel.Text = 'A_p';
            app.A_pEditField = uieditfield(app.AmptitudePanel, 'numeric');
            app.A_pEditField.Value = 1;
            app.A_sEditFieldLabel = uilabel(app.AmptitudePanel);
            app.A_sEditFieldLabel.Text = 'A_s';
            app.A_sEditField = uieditfield(app.AmptitudePanel, 'numeric');
            app.A_sEditField.Value = 20;
            % Show the figure after all components are created
            app.FilterApp_lqy.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterApp_lqy)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterApp_lqy)
        end
    end
end