classdef noise_generate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ButtonGroup        matlab.ui.container.ButtonGroup
        multi_sin          matlab.ui.control.RadioButton
        single_sin         matlab.ui.control.RadioButton
        white_noise        matlab.ui.control.RadioButton
        Single_Sin_Panel   matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        freq_single        matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        single_amptitude   matlab.ui.control.NumericEditField
        Multi_Sin_Panel    matlab.ui.container.Panel
        EditField_2Label   matlab.ui.control.Label
        freq_multi_1       matlab.ui.control.NumericEditField
        EditField_3Label   matlab.ui.control.Label
        freq_multi_2       matlab.ui.control.NumericEditField
        EditField_4Label   matlab.ui.control.Label
        freq_multi_3       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        multi_amptitude    matlab.ui.control.NumericEditField
        White_Noise_Panel  matlab.ui.container.Panel
        dBWEditFieldLabel  matlab.ui.control.Label
        white_noise_power  matlab.ui.control.NumericEditField
        OKButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % 创建私有属性CallingApp用于存储主窗口类
        received_signal;
        received_sampling_rate;
        
        multi_sin_noise;
        single_sin_noise;
        gaussion_white_noise;
        selected_noise;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_window, signal, sampling_rate)
            app.Multi_Sin_Panel.Visible = 'on';
            app.Single_Sin_Panel.Visible = 'off';
            app.White_Noise_Panel.Visible = 'off';
            
            app.CallingApp = Main_window;
            app.received_signal = signal;
            app.received_sampling_rate = sampling_rate;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.multi_sin
                app.Multi_Sin_Panel.Visible = 'on';
                app.Single_Sin_Panel.Visible = 'off';
                app.White_Noise_Panel.Visible = 'off';
            elseif selectedButton == app.single_sin
                app.Multi_Sin_Panel.Visible = 'off';
                app.Single_Sin_Panel.Visible = 'on';
                app.White_Noise_Panel.Visible = 'off';                
            elseif selectedButton == app.white_noise
                app.Multi_Sin_Panel.Visible = 'off';
                app.Single_Sin_Panel.Visible = 'off';
                app.White_Noise_Panel.Visible = 'on';                 
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 计算噪声
            n = 0:length(app.received_signal)-1;
            t = n/app.received_sampling_rate;
            
            sin_freq1 = app.freq_multi_1.Value;
            sin_freq2 = app.freq_multi_2.Value;
            sin_freq3 = app.freq_multi_3.Value;
            
            app.multi_sin_noise = sin(2*pi*sin_freq1*t) + sin(2*pi*sin_freq2*t) + sin(2*pi*sin_freq3*t);
            app.multi_sin_noise = app.multi_sin_noise * app.multi_amptitude.Value;
            
            
            sin_freq_single = app.freq_single.Value;
            
            app.single_sin_noise = sin(2*pi*sin_freq_single*t);
            app.single_sin_noise = app.single_sin_noise * app.single_amptitude.Value;
            
            
            app.gaussion_white_noise = wgn(1,length(n),app.white_noise_power.Value);
            
            % 根据用户选择按钮决定传递给主窗口哪个种类的噪声
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.multi_sin
                app.selected_noise = app.multi_sin_noise;
            elseif selectedButton == app.single_sin
                app.selected_noise = app.single_sin_noise;
            elseif selectedButton == app.white_noise
                app.selected_noise = app.gaussion_white_noise;
            end
            
            Receive_DataFrom_NoiseGenerate(app.CallingApp, app.selected_noise);
            
            app.CallingApp.AddNoiseButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AddNoiseButton.Enable = 'on';
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '噪声选择';
            app.multi_sin = uiradiobutton(app.ButtonGroup);
            app.multi_sin.Text = '多频正弦';
            app.multi_sin.Value = true;
            app.single_sin = uiradiobutton(app.ButtonGroup);
            app.single_sin.Text = '单频正弦';
            app.white_noise = uiradiobutton(app.ButtonGroup);
            app.white_noise.Text = '高斯白噪声';
            app.Single_Sin_Panel = uipanel(app.UIFigure);
            app.Single_Sin_Panel.Title = '单频正弦';
            app.Label = uilabel(app.Single_Sin_Panel);
            app.Label.Text = '正弦频率';
            app.freq_single = uieditfield(app.Single_Sin_Panel, 'numeric');
            app.freq_single.Value = 40;
            app.Label_3 = uilabel(app.Single_Sin_Panel);
            app.Label_3.Text = '幅值';
            app.single_amptitude = uieditfield(app.Single_Sin_Panel, 'numeric');
            app.single_amptitude.Value = 1;
            app.Multi_Sin_Panel = uipanel(app.UIFigure);
            app.Multi_Sin_Panel.Title = '多频正弦';
            app.EditField_2Label = uilabel(app.Multi_Sin_Panel);
            app.EditField_2Label.Text = '正弦频率1';
            app.freq_multi_1 = uieditfield(app.Multi_Sin_Panel, 'numeric');
            app.freq_multi_1.Value = 10;
            app.EditField_3Label = uilabel(app.Multi_Sin_Panel);
            app.EditField_3Label.Text = '正弦频率2';
            app.freq_multi_2 = uieditfield(app.Multi_Sin_Panel, 'numeric');
            app.freq_multi_2.Value = 40;
            app.EditField_4Label = uilabel(app.Multi_Sin_Panel);
            app.EditField_4Label.Text = '正弦频率3';
            app.freq_multi_3 = uieditfield(app.Multi_Sin_Panel, 'numeric');
            app.freq_multi_3.Value = 100;
            app.Label_2 = uilabel(app.Multi_Sin_Panel);
            app.Label_2.Text = '幅值';
            app.multi_amptitude = uieditfield(app.Multi_Sin_Panel, 'numeric');
            app.multi_amptitude.Value = 1;
            app.White_Noise_Panel = uipanel(app.UIFigure);
            app.White_Noise_Panel.Title = '高斯白噪声';
            app.dBWEditFieldLabel = uilabel(app.White_Noise_Panel);
            app.dBWEditFieldLabel.Text = '强度（dBW）';
            app.white_noise_power = uieditfield(app.White_Noise_Panel, 'numeric');
            app.white_noise_power.Value = 1;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_generate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end