classdef sin_signal_generate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        freq1             matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        freq2             matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        freq3             matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        sampling_freq     matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        sampling_length   matlab.ui.control.NumericEditField
        OKButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % 用于存储主窗口类
        sin_signal; % 用于存储计算出来的正弦信号结果
        sampling_rate; % 用于存储用户选择的采样率
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_window)
            app.CallingApp = Main_window;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            sin_freq1 = app.freq1.Value;
            sin_freq2 = app.freq2.Value;
            sin_freq3 = app.freq3.Value;
            app.sampling_rate = app.sampling_freq.Value;
            length = app.sampling_length.Value;
            
            n = 0:length-1;
            t = n/app.sampling_rate;
            
            % app.sin_signal = sin(linspace(-pi, pi, 50));
            app.sin_signal = sin(2*pi*sin_freq1*t) + sin(2*pi*sin_freq2*t) + sin(2*pi*sin_freq3*t);
          
            % 调用主窗中定义的公共函数Receive_DataFrom_SinSignalGenerate，将对话窗的数据sin_signal
            % 返回至主窗
            Receive_DataFrom_SinSignalGenerate(app.CallingApp, app.sin_signal, app.sampling_rate);
            
            app.CallingApp.SinSignalButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SinSignalButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '正弦频率1';
            app.freq1 = uieditfield(app.UIFigure, 'numeric');
            app.freq1.Value = 20;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '正弦频率2';
            app.freq2 = uieditfield(app.UIFigure, 'numeric');
            app.freq2.Value = 30;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '正弦频率3';
            app.freq3 = uieditfield(app.UIFigure, 'numeric');
            app.freq3.Value = 50;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '采样频率';
            app.sampling_freq = uieditfield(app.UIFigure, 'numeric');
            app.sampling_freq.Value = 200;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '采样点数';
            app.sampling_length = uieditfield(app.UIFigure, 'numeric');
            app.sampling_length.Value = 400;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sin_signal_generate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end